classdef lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lab02SealesPrototipoLabel      matlab.ui.control.Label
        axeft                          matlab.ui.control.UIAxes
        MostrarFuncionesButton         matlab.ui.control.Button
        axeht                          matlab.ui.control.UIAxes
        TabGroup                       matlab.ui.container.TabGroup
        ftTab                          matlab.ui.container.Tab
        TipodeSealDropDownLabel        matlab.ui.control.Label
        TipodeSealDropDown             matlab.ui.control.DropDown
        Panel_2                        matlab.ui.container.Panel
        aLabel                         matlab.ui.control.Label
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel         matlab.ui.control.Label
        AmplitudEditField              matlab.ui.control.NumericEditField
        EditField                      matlab.ui.control.NumericEditField
        tEditFieldLabel                matlab.ui.control.Label
        tEditField                     matlab.ui.control.NumericEditField
        htTab                          matlab.ui.container.Tab
        TipodeSealDropDown_2Label      matlab.ui.control.Label
        TipodeSealDropDown_2           matlab.ui.control.DropDown
        Panel_4                        matlab.ui.container.Panel
        aLabel_2                       matlab.ui.control.Label
        FrecuenciaEditField_2Label     matlab.ui.control.Label
        FrecuenciaEditField_2          matlab.ui.control.NumericEditField
        AmplitudEditField_2Label       matlab.ui.control.Label
        AmplitudEditField_2            matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        tEditField_2Label              matlab.ui.control.Label
        tEditField_2                   matlab.ui.control.NumericEditField
        axposicion                     matlab.ui.control.UIAxes
        axconvo                        matlab.ui.control.UIAxes
        ConvolucionButton              matlab.ui.control.Button
        DominiodelTiempoDropDownLabel  matlab.ui.control.Label
        DominiodelTiempoDropDown       matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function setf(~,xval,yval)
            global f
            f=[xval;yval];
        end


        function seth(~,xval,yval)
            global h 
            h=[xval;yval];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Panel_2, TipodeSealDropDown, 
        % TipodeSealDropDown_2
        function DropDownValueChanged(app, event)
            funcionf = app.TipodeSealDropDown.Value;
            
            
            app.FrecuenciaEditField.Visible=true;
            app.FrecuenciaEditFieldLabel.Visible=true;
            app.AmplitudEditField.Visible=true;
            app.AmplitudEditFieldLabel.Visible=true;
            
            
            switch funcionf
                case " "
                    app.Panel_2.Visible = false;
                    
                case "Seno"
                    app.Panel_2.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text = "Periodo";
                    app.AmplitudEditFieldLabel.Text = "Amplitud";
                    if app.DominiodelTiempoDropDown=="Discreta"
                      app.aLabel.Text = "y[n]=A*Sen(2*π*[n]/No)";
                    else
                      app.aLabel.Text = "y(t)=A*Sen(2*π*t/T)";
                    end
                    app.aLabel.Visible = true;
                    app.tEditField.Visible = false;
                    app.EditField.Visible = false;
                    app.tEditFieldLabel.Visible = false;
                    
                case "Cuadrada"
                    app.Panel_2.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text="Periodo";
                    app.AmplitudEditFieldLabel.Text="Amplitud";
                    app.aLabel.Visible = false;
                    app.tEditField.Visible = false;
                    app.EditField.Visible = false;
                    app.tEditFieldLabel.Visible = false;
                    
                case "Exponencial"
                    app.Panel_2.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      app.aLabel.Text = "y[n]=A*exp(-b*n)";
                      app.tEditFieldLabel.Text="< n <";
                    else
                      app.aLabel.Text = "y(t)=A*exp(-b*t)";
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text="A";
                    app.AmplitudEditFieldLabel.Text="b";
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                case "Rampa 1"
                    app.Panel_2.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditFieldLabel.Text="< n <";
                    else
                      
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = false;
                    app.FrecuenciaEditFieldLabel.Text="a";
                    app.FrecuenciaEditField.Visible=false;
                    app.FrecuenciaEditFieldLabel.Visible=false;
                    app.AmplitudEditFieldLabel.Text="b";
                    app.AmplitudEditField.Visible=false;
                    app.AmplitudEditFieldLabel.Visible=false;
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                                
                case "Triangular"
                    app.Panel_2.Visible = true;
                    app.FrecuenciaEditFieldLabel.Text="Periodo";
                    app.AmplitudEditFieldLabel.Text="Amplitud";
                    app.aLabel.Visible = false;
                    app.tEditField.Visible = false;
                    app.EditField.Visible = false;
                    app.tEditFieldLabel.Visible = false;
                    
                case "Rampa 2"
                    app.Panel_2.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditFieldLabel.Text="< n <";
                    else
                      
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = false;
                    app.FrecuenciaEditFieldLabel.Text="m";
                    app.FrecuenciaEditField.Visible=false;
                    app.FrecuenciaEditFieldLabel.Visible=false;
                    app.AmplitudEditFieldLabel.Text="b";                    
                    app.AmplitudEditField.Visible=false;
                    app.AmplitudEditFieldLabel.Visible=false;
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                    
                case "Rampa 3"
                    app.Panel_2.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditFieldLabel.Text="< n <";
                    else
                      
                      app.tEditFieldLabel.Text="< t <";
                    end
                    app.aLabel.Visible = false;
                    app.FrecuenciaEditFieldLabel.Text="m";
                    app.FrecuenciaEditField.Visible=false;
                    app.FrecuenciaEditFieldLabel.Visible=false;
                    app.AmplitudEditFieldLabel.Text="b";                    
                    app.AmplitudEditField.Visible=false;
                    app.AmplitudEditFieldLabel.Visible=false;
                    app.tEditField.Visible = true;
                    app.EditField.Visible = true;
                    app.tEditFieldLabel.Visible = true;
                    
      
            end
            
            %impulso
            funcionh = app.TipodeSealDropDown_2.Value;
            app.FrecuenciaEditField_2.Visible=true;
            app.FrecuenciaEditField_2Label.Visible=true;
            app.AmplitudEditField_2.Visible=true;
            app.AmplitudEditField_2Label.Visible=true;
            
            switch funcionh 
                case " "
                    app.Panel_4.Visible = false;
                    
                case "Seno"
                    app.Panel_4.Visible = true;
                    app.FrecuenciaEditField_2Label.Text = "Periodo";
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2Label.Text = "Amplitud";
                    if app.DominiodelTiempoDropDown=="Discreta"
                      app.aLabel_2.Text = "y[n]=A*Sen(2*π*[n]/No)";
                      app.tEditField_2Label.Text="< n <";
                    else
                      app.aLabel_2.Text = "y(t)=A*Sen(2*π*t/T)";
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.aLabel_2.Visible = true;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Cuadrada"
                    app.Panel_4.Visible = true;
                    app.FrecuenciaEditField_2Label.Text = "Periodo";
                    app.AmplitudEditField_2Label.Text = "Amplitud";
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.aLabel_2.Visible = false;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Exponencial"
                    app.Panel_4.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      app.aLabel_2.Text = "y[n]=A*exp(-b*n)";
                      app.tEditField_2Label.Text="< n <";
                    else
                      app.aLabel_2.Text = "y(t)=A*exp(-b*t)";
                      app.tEditField_2Label.Text="< t <";
                    end
                     
                    app.aLabel_2.Visible = true;
                    app.FrecuenciaEditField_2Label.Text="A";
                    app.AmplitudEditField_2Label.Text="b";
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Rampa 1"
                    app.Panel_4.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end

                    app.aLabel_2.Visible = false;
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2.Visible=false;
                    app.AmplitudEditField_2Label.Visible=false;
                    app.FrecuenciaEditField_2Label.Text="a";
                    app.AmplitudEditField_2Label.Text="b";
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Triangular"
                    app.Panel_4.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.FrecuenciaEditField_2Label.Text = "Periodo";
                    app.AmplitudEditField_2Label.Text = "Amplitud";
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.aLabel_2.Visible = false;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Rampa 2"
                    app.Panel_4.Visible = true;
                    
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.aLabel_2.Visible = false;
                    
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2.Visible=false;
                    app.AmplitudEditField_2Label.Visible=false;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;
                    
                case "Rampa 3"
                    app.Panel_4.Visible = true;
                    if app.DominiodelTiempoDropDown=="Discreta"
                      
                      app.tEditField_2Label.Text="< n <";
                    else
                      
                      app.tEditField_2Label.Text="< t <";
                    end
                    app.aLabel_2.Visible = false;
                    
                    app.FrecuenciaEditField_2.Visible=false;
                    app.FrecuenciaEditField_2Label.Visible=false;
                    app.AmplitudEditField_2.Visible=false;
                    app.AmplitudEditField_2Label.Visible=false;
                    app.tEditField_2.Visible = true;
                    app.EditField_2.Visible = true;
                    app.tEditField_2Label.Visible = true;


            end


        end

        % Button pushed function: MostrarFuncionesButton
        function MostrarFuncionesButtonPushed(app, event)
            
            if (app.TipodeSealDropDown.Value~=" " && app.TipodeSealDropDown_2~=" ")
            
                
            else
                
                
            end
        end

        % Value changed function: DominiodelTiempoDropDown
        function DominiodelTiempoDropDownValueChanged(app, event)
            value = app.DominiodelTiempoDropDown.Value;
            
            switch value
                case " "
                    app.TabGroup.Visible=false;
                    app.ConvolucionButton.Visible=false;
                    app.MostrarFuncionesButton.Visible=false;
                    app.axconvo.Visible
                    
                case "Discreta"
                    app.ftTab.Title="f[n]";
                    app.htTab.Title="h[n]";
                    app.axeft.Title.String="f[n]";
                    app.axeht.Title.String="h[n]";
                    
                    app.TabGroup.Visible=true;
                    app.ConvolucionButton.Visible=true;
                    app.MostrarFuncionesButton.Visible=true;
                    switch app.TipodeSealDropDown.Value
                        case "Seno"
                            app.aLabel.Text = "y[n]=A*Sen(2*π*[n]/No)";
                        case "Exponencial"
                            app.aLabel.Text = "y[n]=A*exp(-b*n)";
                            app.tEditFieldLabel.Text="< n <";
                        case "Rampa 1"
                            app.tEditFieldLabel.Text="< n <";
                        case "Rampa 2"
                            app.tEditFieldLabel.Text="< n <";
                        case "Rampa 3"
                            app.tEditFieldLabel.Text="< n <";
                            
                    end
                    switch app.TipodeSealDropDown_2.Value
                        case "Seno"
                            app.aLabel_2.Text = "y[n]=A*Sen(2*π*[n]/No)";
                        case "Exponencial"
                            app.aLabel_2.Text = "y[n]=A*exp(-b*n)";
                            app.tEditField_2Label.Text="< n <";
                        case "Rampa 1"
                            app.tEditField_2Label.Text="< n <";
                        case "Rampa 2"
                            app.tEditField_2Label.Text="< n <";
                        case "Rampa 3"
                            app.tEditField_2Label.Text="< n <";
                    
                    end
                        
                case "Continua"
                    app.ftTab.Title="f(t)";
                    app.htTab.Title="h(t)";
                    app.axeft.Title.String="f(t)";
                    app.axeht.Title.String="h(t)";
                    app.TabGroup.Visible=true;
                    app.ConvolucionButton.Visible=true;
                    app.MostrarFuncionesButton.Visible=true;
                    switch app.TipodeSealDropDown.Value
                        case "Seno"
                            app.aLabel.Text = "y(t)=A*Sen(2*π*t/T)";
                        case "Exponencial"
                            app.aLabel.Text = "y(t)=A*exp(-b*t)";
                            app.tEditFieldLabel.Text="< t <";
                        case "Rampa 1"
                            app.tEditFieldLabel.Text="< t <";
                        case "Rampa 2"
                            app.tEditFieldLabel.Text="< t <";
                        case "Rampa 3"
                            app.tEditFieldLabel.Text="< t <";
                    end
                    switch app.TipodeSealDropDown_2.Value
                        case "Seno"
                            app.aLabel_2.Text = "y(t)=A*Sen(2*π*t/T)";
                        case "Exponencial"
                            app.aLabel_2.Text = "y(t)=A*exp(-b*t)";
                            app.tEditField_2Label.Text="< t <";
                        case "Rampa 1"
                            app.tEditField_2Label.Text="< t <";
                        case "Rampa 2"
                            app.tEditField_2Label.Text="< t <";
                        case "Rampa 3"
                            app.tEditField_2Label.Text="< t <";
                    end
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lab02SealesPrototipoLabel = uilabel(app.UIFigure);
            app.Lab02SealesPrototipoLabel.Text = 'Lab02 Señales Prototipo';
            app.axeft = uiaxes(app.UIFigure);
            title(app.axeft, '')
            xlabel(app.axeft, 't')
            ylabel(app.axeft, 'Y')
            app.MostrarFuncionesButton = uibutton(app.UIFigure, 'push');
            app.MostrarFuncionesButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarFuncionesButtonPushed, true);
            app.MostrarFuncionesButton.Visible = 'off';
            app.MostrarFuncionesButton.Text = 'Mostrar Funciones';
            app.axeht = uiaxes(app.UIFigure);
            title(app.axeht, '')
            xlabel(app.axeht, 't')
            ylabel(app.axeht, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.ftTab = uitab(app.TabGroup);
            app.ftTab.Title = 'f(t)';
            app.TipodeSealDropDownLabel = uilabel(app.ftTab);
            app.TipodeSealDropDownLabel.Text = 'Tipo de Señal';
            app.TipodeSealDropDown = uidropdown(app.ftTab);
            app.TipodeSealDropDown.Items = {' ', 'Seno', 'Exponencial', 'Cuadrada', 'Triangular', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.TipodeSealDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.TipodeSealDropDown.Value = ' ';
            app.Panel_2 = uipanel(app.ftTab);
            app.Panel_2.Visible = 'off';
            app.Panel_2.SizeChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.aLabel = uilabel(app.Panel_2);
            app.aLabel.Text = 'a';
            app.FrecuenciaEditFieldLabel = uilabel(app.Panel_2);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.Panel_2, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.Panel_2);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.Panel_2, 'numeric');
            app.EditField = uieditfield(app.Panel_2, 'numeric');
            app.tEditFieldLabel = uilabel(app.Panel_2);
            app.tEditFieldLabel.Text = '< t <';
            app.tEditField = uieditfield(app.Panel_2, 'numeric');
            app.htTab = uitab(app.TabGroup);
            app.htTab.Title = 'h(t)';
            app.TipodeSealDropDown_2Label = uilabel(app.htTab);
            app.TipodeSealDropDown_2Label.Text = 'Tipo de Señal';
            app.TipodeSealDropDown_2 = uidropdown(app.htTab);
            app.TipodeSealDropDown_2.Items = {' ', 'Seno', 'Exponencial', 'Cuadrada', 'Triangular', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.TipodeSealDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.TipodeSealDropDown_2.Value = ' ';
            app.Panel_4 = uipanel(app.htTab);
            app.Panel_4.Visible = 'off';
            app.aLabel_2 = uilabel(app.Panel_4);
            app.aLabel_2.Text = 'a';
            app.FrecuenciaEditField_2Label = uilabel(app.Panel_4);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.AmplitudEditField_2Label = uilabel(app.Panel_4);
            app.AmplitudEditField_2Label.Text = 'Amplitud';
            app.AmplitudEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.tEditField_2Label = uilabel(app.Panel_4);
            app.tEditField_2Label.Text = '< t <';
            app.tEditField_2 = uieditfield(app.Panel_4, 'numeric');
            app.axposicion = uiaxes(app.UIFigure);
            title(app.axposicion, {'Posicion'; ''})
            xlabel(app.axposicion, 't')
            ylabel(app.axposicion, 'Y')
            app.axconvo = uiaxes(app.UIFigure);
            title(app.axconvo, {'Convolucion'; ''})
            xlabel(app.axconvo, 't')
            ylabel(app.axconvo, 'Y')
            app.ConvolucionButton = uibutton(app.UIFigure, 'push');
            app.ConvolucionButton.Visible = 'off';
            app.ConvolucionButton.Text = 'Convolucion';
            app.DominiodelTiempoDropDownLabel = uilabel(app.UIFigure);
            app.DominiodelTiempoDropDownLabel.Text = 'Dominio del Tiempo';
            app.DominiodelTiempoDropDown = uidropdown(app.UIFigure);
            app.DominiodelTiempoDropDown.Items = {' ', 'Continua', 'Discreta'};
            app.DominiodelTiempoDropDown.ValueChangedFcn = createCallbackFcn(app, @DominiodelTiempoDropDownValueChanged, true);
            app.DominiodelTiempoDropDown.Value = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end